classdef svd_forensic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        forensicanalysisButton  matlab.ui.control.Button
        statusLabel             matlab.ui.control.Label
        loadimageButton         matlab.ui.control.Button
        BSpinner                matlab.ui.control.Spinner
        BSpinnerLabel           matlab.ui.control.Label
        TsSlider                matlab.ui.control.Slider
        TsSliderLabel           matlab.ui.control.Label
        T1Slider                matlab.ui.control.Slider
        T1SliderLabel           matlab.ui.control.Label
        dstUIAxes               matlab.ui.control.UIAxes
        srcUIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        B = 0 % Description
        T1 = 0 % Description
        Ts = 0 % Description
        srcimgfname % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.srcUIAxes.Visible = 'off';
            app.srcUIAxes.Colormap = gray(256);
            axis(app.srcUIAxes, 'image');
            
            app.dstUIAxes.Visible = 'off';
            app.dstUIAxes.Colormap = gray(256);
            axis(app.dstUIAxes, 'image');
            
            app.B = app.BSpinner.Value; % Description
            app.T1 = app.T1Slider.Value; % Description
            app.Ts = app.TsSlider.Value; % Description
        end

        % Button pushed function: loadimageButton
        function loadimageButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.srcimgfname = [p f];
                srcimg=imread(app.srcimgfname);
                imagesc(app.srcUIAxes,srcimg)
            end
        end

        % Value changing function: BSpinner
        function BSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.B = changingValue;
        end

        % Value changing function: TsSlider
        function TsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Ts = changingValue;
        end

        % Value changing function: T1Slider
        function T1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.T1 = changingValue;
        end

        % Button pushed function: forensicanalysisButton
        function forensicanalysisButtonPushed(app, event)
            app.statusLabel.Text = "analysing";
            drawnow
            src = imread(app.srcimgfname);
            dst = svdsvdsvd(src,app.B,app.T1,app.Ts);
            app.statusLabel.Text = "done";
            drawnow
            imagesc(app.dstUIAxes,dst)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.srcUIAxes = uiaxes(app.UIFigure);
            app.srcUIAxes.XTick = [];
            app.srcUIAxes.YTick = [];
            app.dstUIAxes = uiaxes(app.UIFigure);
            app.dstUIAxes.XTick = [];
            app.dstUIAxes.YTick = [];
            app.T1SliderLabel = uilabel(app.UIFigure);
            app.T1SliderLabel.Text = 'T1';
            app.T1Slider = uislider(app.UIFigure);
            app.T1Slider.Limits = [0 0.1];
            app.T1Slider.ValueChangingFcn = createCallbackFcn(app, @T1SliderValueChanging, true);
            app.T1Slider.Value = 0.05;
            app.TsSliderLabel = uilabel(app.UIFigure);
            app.TsSliderLabel.Text = 'Ts';
            app.TsSlider = uislider(app.UIFigure);
            app.TsSlider.Limits = [0 1000];
            app.TsSlider.ValueChangingFcn = createCallbackFcn(app, @TsSliderValueChanging, true);
            app.TsSlider.Value = 400;
            app.BSpinnerLabel = uilabel(app.UIFigure);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.UIFigure);
            app.BSpinner.ValueChangingFcn = createCallbackFcn(app, @BSpinnerValueChanging, true);
            app.BSpinner.Value = 20;
            app.loadimageButton = uibutton(app.UIFigure, 'push');
            app.loadimageButton.ButtonPushedFcn = createCallbackFcn(app, @loadimageButtonPushed, true);
            app.loadimageButton.Text = 'load image';
            app.statusLabel = uilabel(app.UIFigure);
            app.statusLabel.Text = 'status';
            app.forensicanalysisButton = uibutton(app.UIFigure, 'push');
            app.forensicanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @forensicanalysisButtonPushed, true);
            app.forensicanalysisButton.Text = 'forensic analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svd_forensic_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end